classdef Online_Loading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnlineSavingUIFigure     matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ChannelUsing_8           matlab.ui.control.CheckBox
        ChannelUsing_7           matlab.ui.control.CheckBox
        ChannelUsing_6           matlab.ui.control.CheckBox
        ChannelUsing_5           matlab.ui.control.CheckBox
        ChannelUsing_4           matlab.ui.control.CheckBox
        ChannelUsing_3           matlab.ui.control.CheckBox
        ChannelUsing_2           matlab.ui.control.CheckBox
        ChannelUsing             matlab.ui.control.CheckBox
        Spinners24               matlab.ui.control.Spinner
        SEG21Label_4             matlab.ui.control.Label
        Spinners23               matlab.ui.control.Spinner
        SEG21Label_3             matlab.ui.control.Label
        Spinners22               matlab.ui.control.Spinner
        SEG21Label_2             matlab.ui.control.Label
        Spinners21               matlab.ui.control.Spinner
        SEG21Label               matlab.ui.control.Label
        Spinners14               matlab.ui.control.Spinner
        SEG14Label               matlab.ui.control.Label
        Spinners13               matlab.ui.control.Spinner
        SEG13Label               matlab.ui.control.Label
        Spinners12               matlab.ui.control.Spinner
        SEG12Label               matlab.ui.control.Label
        Spinners11               matlab.ui.control.Spinner
        SEG11SpinnerLabel        matlab.ui.control.Label
        ElectrodeAdvancedButton  matlab.ui.control.Button
        SelectSet                matlab.ui.control.DropDown
        DatasetDropDownLabel     matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        SavingLamp               matlab.ui.control.Lamp
        SavingLampLabel          matlab.ui.control.Label
        ResumeTodayButton        matlab.ui.control.Button
        StopOnlineSavingButton   matlab.ui.control.Button
        StartOnlineSavingButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global BAM_config GAM_data
            
            root_dir = fN_localize_app;
            addpath(genpath(pwd))
            [BAM_config, GAM_data, app] = fN_initialize(app);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OnlineSavingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartOnlineSavingButton
        function StartOnlineSavingButtonPushed(app, event)
            global BAM_config GAM_data
            BAM_config.is_saving = 1;
            app = fN_change_gui_color(app);
            app.SavingLampLabel.Text='Saving';
        end

        % Button pushed function: StopOnlineSavingButton
        function StopOnlineSavingButtonPushed(app, event)
            global BAM_config GAM_data
            BAM_config.is_saving = 0;
            app = fN_change_gui_color(app);
            app.SavingLampLabel.Text='Stopped';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnlineSavingUIFigure = uifigure('Visible', 'off');
            app.OnlineSavingUIFigure.Name = 'Online Saving';
            app.OnlineSavingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OnlineSavingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.SelectSet = uidropdown(app.LeftPanel);
            app.SelectSet.Items = {'Option 3', 'Option 4'};
            app.SelectSet.Value = 'Option 3';
            app.ElectrodeAdvancedButton = uibutton(app.LeftPanel, 'push');
            app.ElectrodeAdvancedButton.Text = 'Electrode Advanced';
            app.SEG11SpinnerLabel = uilabel(app.LeftPanel);
            app.SEG11SpinnerLabel.Text = 'SEG1-1';
            app.Spinners11 = uispinner(app.LeftPanel);
            app.SEG12Label = uilabel(app.LeftPanel);
            app.SEG12Label.Text = 'SEG1-2';
            app.Spinners12 = uispinner(app.LeftPanel);
            app.SEG13Label = uilabel(app.LeftPanel);
            app.SEG13Label.Text = 'SEG1-3';
            app.Spinners13 = uispinner(app.LeftPanel);
            app.SEG14Label = uilabel(app.LeftPanel);
            app.SEG14Label.Text = 'SEG1-4';
            app.Spinners14 = uispinner(app.LeftPanel);
            app.SEG21Label = uilabel(app.LeftPanel);
            app.SEG21Label.Text = 'SEG2-1';
            app.Spinners21 = uispinner(app.LeftPanel);
            app.SEG21Label_2 = uilabel(app.LeftPanel);
            app.SEG21Label_2.Text = 'SEG2-2';
            app.Spinners22 = uispinner(app.LeftPanel);
            app.SEG21Label_3 = uilabel(app.LeftPanel);
            app.SEG21Label_3.Text = 'SEG2-3';
            app.Spinners23 = uispinner(app.LeftPanel);
            app.SEG21Label_4 = uilabel(app.LeftPanel);
            app.SEG21Label_4.Text = 'SEG2-4';
            app.Spinners24 = uispinner(app.LeftPanel);
            app.ChannelUsing = uicheckbox(app.LeftPanel);
            app.ChannelUsing.Text = 'enable';
            app.ChannelUsing_2 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_2.Text = 'enable';
            app.ChannelUsing_3 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_3.Text = 'enable';
            app.ChannelUsing_4 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_4.Text = 'enable';
            app.ChannelUsing_5 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_5.Text = 'enable';
            app.ChannelUsing_6 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_6.Text = 'enable';
            app.ChannelUsing_7 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_7.Text = 'enable';
            app.ChannelUsing_8 = uicheckbox(app.LeftPanel);
            app.ChannelUsing_8.Text = 'enable';
            app.RightPanel = uipanel(app.GridLayout);
            app.StartOnlineSavingButton = uibutton(app.RightPanel, 'push');
            app.StartOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StartOnlineSavingButtonPushed, true);
            app.StartOnlineSavingButton.Text = 'Start Online Saving';
            app.StopOnlineSavingButton = uibutton(app.RightPanel, 'push');
            app.StopOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StopOnlineSavingButtonPushed, true);
            app.StopOnlineSavingButton.Text = 'Stop Online Saving';
            app.ResumeTodayButton = uibutton(app.RightPanel, 'push');
            app.ResumeTodayButton.Text = 'Resume Today';
            app.SavingLampLabel = uilabel(app.RightPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.OnlineSavingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Online_Loading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnlineSavingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnlineSavingUIFigure)
        end
    end
end